classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SliderControlsPanel        matlab.ui.container.Panel
        ZAxisRotationSlider        matlab.ui.control.Slider
        ZAxisRotationSliderLabel   matlab.ui.control.Label
        YAxisRotationSlider        matlab.ui.control.Slider
        YAxisRotationSliderLabel   matlab.ui.control.Label
        XAxisRotationSlider        matlab.ui.control.Slider
        XAxisRotationSliderLabel   matlab.ui.control.Label
        ZTranslationSlider         matlab.ui.control.Slider
        ZTranslationSliderLabel    matlab.ui.control.Label
        YTranslationSlider         matlab.ui.control.Slider
        YTranslationSliderLabel    matlab.ui.control.Label
        XTranslationSlider         matlab.ui.control.Slider
        XTranslationSliderLabel    matlab.ui.control.Label
        TransformationMatrixPanel  matlab.ui.container.Panel
        MatrixField44              matlab.ui.control.NumericEditField
        MatrixField33              matlab.ui.control.NumericEditField
        MatrixField34              matlab.ui.control.NumericEditField
        MatrixField24              matlab.ui.control.NumericEditField
        MatrixField43              matlab.ui.control.NumericEditField
        MatrixField23              matlab.ui.control.NumericEditField
        MatrixField14              matlab.ui.control.NumericEditField
        MatrixField12              matlab.ui.control.NumericEditField
        MatrixField22              matlab.ui.control.NumericEditField
        MatrixField32              matlab.ui.control.NumericEditField
        MatrixField13              matlab.ui.control.NumericEditField
        MatrixField11              matlab.ui.control.NumericEditField
        MatrixField42              matlab.ui.control.NumericEditField
        MatrixField41              matlab.ui.control.NumericEditField
        MatrixField31              matlab.ui.control.NumericEditField
        MatrixField21              matlab.ui.control.NumericEditField
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        shiftX % The shift in position for the X-Axis (Coil)
        shiftY % The shift in position for the Y-Axis (Coil)
        shiftZ % The shift in position for the Z-Axis (Coil)
        P % Description
    end
    
    methods (Access = public)
        
        function [] = updateImage(app)
           
            updateimage(app); % calls function in AppFunctuons/updateimage.m

        end
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            addpathes();
            
            load('CombinedMesh.mat', "P")
            app.P = P;
            
            %sets a few properties of the UIAxes
            grid(app.UIAxes,'on')
            app.UIAxes.XLim = [-0.2, 0.2];
            app.UIAxes.YLim = [-0.2, 0.2];
            app.UIAxes.ZLim = [-0.2, 0.2];

            %displays the initial image:
            updateImage(app);
        end

        % Value changed function: MatrixField11, MatrixField12, 
        % ...and 14 other components
        function MatrixFieldValueChanged(app, event)

            %   Update the values of the sliders to reflect the new values
            %   of the matrix


            %   Update the image
            updateImage(app);
            
        end

        % Value changed function: XAxisRotationSlider, XTranslationSlider, 
        % ...and 4 other components
        function SliderValueChanged(app, event)
            
            %   Update the values of the matrix to reflect the new values
            %   of the sliders

            app.MatrixField14.Value = app.XTranslationSlider.Value; %   Update X Translation
            app.MatrixField24.Value = app.YTranslationSlider.Value; %   Update Y Translation
            app.MatrixField34.Value = app.ZTranslationSlider.Value; %   Update Z Translation

            XAngle = pi*app.XAxisRotationSlider.Value; %  X-Axis rotation in pi*rads
            YAngle = pi*app.YAxisRotationSlider.Value; %  Y-Axis rotation in pi*rads
            ZAngle = pi*app.ZAxisRotationSlider.Value; %  Z-Axis rotation in pi*rads


%             Rx = [1, 0, 0; 
%                 0, cos(XAngle), -sin(XAngle); 
%                 0, sin(XAngle), cos(XAngle)];    %X's rotation matrix
% 
%             Ry = [cos(YAngle), 0, sin(YAngle);
%                 0, 1, 0;
%                 -sin(YAngle), 0, cos(YAngle)];  %Y's rotation matrix
% 
%             Rz = [cos(ZAngle), -sin(ZAngle), 0;
%                 sin(ZAngle), cos(ZAngle), 0;
%                 0, 0, 1];    %Z's rotation matrix
% 
%             Rtotal1 = Rx*Ry*Rz;
%             Rtotal2 = Rx*Rz*Ry;
%             Rtotal3 = Ry*Rx*Rz;
%             Rtotal4 = Ry*Rz*Rx;
%             Rtotal5 = Rz*Rx*Ry;
%             Rtotal6 = Rz*Ry*Rx;
% 
%             disp(isequal(Rtotal1, Rtotal2, Rtotal3, Rtotal4, Rtotal5, Rtotal6))


            %   Update the image
            updateImage(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.TransformationMatrixPanel = uipanel(app.UIFigure);
            app.TransformationMatrixPanel.Title = 'Transformation Matrix';
            app.MatrixField21 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField21.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField31 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField31.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField41 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField41.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField41.Editable = 'off';
            app.MatrixField42 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField42.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField42.Editable = 'off';
            app.MatrixField11 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField11.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField13 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField13.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField32 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField32.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField22 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField22.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField12 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField12.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField14 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField14.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField23 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField23.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField43 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField43.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField43.Editable = 'off';
            app.MatrixField24 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField24.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField34 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField34.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField33 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField33.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField44 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField44.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField44.Editable = 'off';
            app.MatrixField44.Value = 1;
            app.SliderControlsPanel = uipanel(app.UIFigure);
            app.SliderControlsPanel.Title = 'Slider Controls';
            app.XTranslationSliderLabel = uilabel(app.SliderControlsPanel);
            app.XTranslationSliderLabel.Tooltip = {'The x-component of the coil''s translation'};
            app.XTranslationSliderLabel.Text = 'X Translation';
            app.XTranslationSlider = uislider(app.SliderControlsPanel);
            app.XTranslationSlider.Limits = [-0.2 0.2];
            app.XTranslationSlider.MajorTicks = [-0.2 -0.1 0 0.1 0.2];
            app.XTranslationSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.XTranslationSlider.Tooltip = {'The x-component of the coil''s translation'};
            app.YTranslationSliderLabel = uilabel(app.SliderControlsPanel);
            app.YTranslationSliderLabel.Tooltip = {'The y-component of the coil''s translation'};
            app.YTranslationSliderLabel.Text = 'Y Translation';
            app.YTranslationSlider = uislider(app.SliderControlsPanel);
            app.YTranslationSlider.Limits = [-0.2 0.2];
            app.YTranslationSlider.MajorTicks = [-0.2 -0.1 0 0.1 0.2];
            app.YTranslationSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.YTranslationSlider.Tooltip = {'The y-component of the coil''s translation'};
            app.ZTranslationSliderLabel = uilabel(app.SliderControlsPanel);
            app.ZTranslationSliderLabel.Tooltip = {'The z-component of the coil''s translation'};
            app.ZTranslationSliderLabel.Text = 'Z Translation';
            app.ZTranslationSlider = uislider(app.SliderControlsPanel);
            app.ZTranslationSlider.Limits = [-0.2 0.2];
            app.ZTranslationSlider.MajorTicks = [-0.2 -0.1 0 0.1 0.2];
            app.ZTranslationSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ZTranslationSlider.Tooltip = {'The z-component of the coil''s translation'};
            app.XAxisRotationSliderLabel = uilabel(app.SliderControlsPanel);
            app.XAxisRotationSliderLabel.Text = 'X-Axis Rotation';
            app.XAxisRotationSlider = uislider(app.SliderControlsPanel);
            app.XAxisRotationSlider.Limits = [0 1];
            app.XAxisRotationSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.XAxisRotationSlider.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1'};
            app.XAxisRotationSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.XAxisRotationSlider.MinorTicks = [0.125 0.375 0.625 0.875];
            app.YAxisRotationSliderLabel = uilabel(app.SliderControlsPanel);
            app.YAxisRotationSliderLabel.Text = 'Y-Axis Rotation';
            app.YAxisRotationSlider = uislider(app.SliderControlsPanel);
            app.YAxisRotationSlider.Limits = [0 1];
            app.YAxisRotationSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.YAxisRotationSlider.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1'};
            app.YAxisRotationSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.YAxisRotationSlider.MinorTicks = [0.125 0.375 0.625 0.875];
            app.ZAxisRotationSliderLabel = uilabel(app.SliderControlsPanel);
            app.ZAxisRotationSliderLabel.Text = 'Z-Axis Rotation';
            app.ZAxisRotationSlider = uislider(app.SliderControlsPanel);
            app.ZAxisRotationSlider.Limits = [0 1];
            app.ZAxisRotationSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.ZAxisRotationSlider.MajorTickLabels = {'0', '0.25', '0.5', '0.75', '1'};
            app.ZAxisRotationSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ZAxisRotationSlider.MinorTicks = [0.125 0.375 0.625 0.875];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end